classdef MathGraphs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MathGraphsUIFigure            matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        TabPoly                       matlab.ui.container.Tab
        GridLayout                    matlab.ui.container.GridLayout
        HTMLx4_2                      matlab.ui.control.HTML
        PolyClearButton               matlab.ui.control.Button
        PolyAddButton                 matlab.ui.control.Button
        HTMLx3                        matlab.ui.control.HTML
        HTMLoffset                    matlab.ui.control.HTML
        HTMLx2                        matlab.ui.control.HTML
        HTMLx4                        matlab.ui.control.HTML
        PolySliderX                   matlab.ui.control.Slider
        PolyShowGraphButton           matlab.ui.control.Button
        Poly0EditField                matlab.ui.control.NumericEditField
        Poly1EditField                matlab.ui.control.NumericEditField
        Poly2EditField                matlab.ui.control.NumericEditField
        Poly5EditField                matlab.ui.control.NumericEditField
        Poly4EditField                matlab.ui.control.NumericEditField
        Poly3EditField                matlab.ui.control.NumericEditField
        PolySkalaAutoSwitch           matlab.ui.control.Switch
        AutoLabel                     matlab.ui.control.Label
        PolySliderY                   matlab.ui.control.Slider
        HTMLx1                        matlab.ui.control.HTML
        PolyErrorLabel                matlab.ui.control.Label
        PolyUIAxes                    matlab.ui.control.UIAxes
        TabTrigo                      matlab.ui.container.Tab
        TrigoShowGraphButton          matlab.ui.control.Button
        TrigoDropDown                 matlab.ui.control.DropDown
        TrigoWertEditField            matlab.ui.control.EditField
        AmplitudeSpinnerLabel         matlab.ui.control.Label
        TrigoAmplitudeSpinner         matlab.ui.control.Spinner
        TrigoAddButton                matlab.ui.control.Button
        TrigoClearButton              matlab.ui.control.Button
        SkalierungYEditFieldLabel     matlab.ui.control.Label
        SkalierungYEditField          matlab.ui.control.NumericEditField
        SkalierungXEditFieldLabel     matlab.ui.control.Label
        SkalierungXEditField          matlab.ui.control.NumericEditField
        TrigoErrorLabel1              matlab.ui.control.Label
        EditField                     matlab.ui.control.EditField
        EditField3                    matlab.ui.control.EditField
        EditField2                    matlab.ui.control.NumericEditField
        TrigoUIAxes                   matlab.ui.control.UIAxes
        TabFrei                       matlab.ui.container.Tab
        GridLayout2                   matlab.ui.container.GridLayout
        FreiErrorLabel4               matlab.ui.control.Label
        FreiErrorLabel3               matlab.ui.control.Label
        FreiFunktion4Label            matlab.ui.control.Label
        FreiFunktion3Label            matlab.ui.control.Label
        FreiWertEditField_3           matlab.ui.control.EditField
        FreiWertEditField_4           matlab.ui.control.EditField
        FreiErrorLabel2               matlab.ui.control.Label
        FreiErrorLabel1               matlab.ui.control.Label
        FreiSkalaLogarythmierenLabel  matlab.ui.control.Label
        FreiFunktion2Label            matlab.ui.control.Label
        FreiFunktion1Label            matlab.ui.control.Label
        FreiWertEditField_1           matlab.ui.control.EditField
        FreiWertEditField_2           matlab.ui.control.EditField
        FreiXSwitch                   matlab.ui.control.Switch
        XSwitchLabel                  matlab.ui.control.Label
        FreiYSwitch                   matlab.ui.control.Switch
        YSwitchLabel                  matlab.ui.control.Label
        FreiShowGraphButton           matlab.ui.control.Button
        FreiUIAxes                    matlab.ui.control.UIAxes
        HistorieTab                   matlab.ui.container.Tab
        UITable                       matlab.ui.control.Table
        HistoriederausgefhrtenFreitextFunktionenLabel  matlab.ui.control.Label
    end


    methods (Access = private)
        
        function TrigoPlotGraph(app,a,func,value,NetxPlotMode)
            
            if func == "Sinus"
                y = strcat(a, "*sin(", value, ")");
            elseif func == "Cosinus"
                y = strcat(a, "*cos(", value, ")");
            else
                y = strcat(a, "*tan(", value, ")");
            end
            
            app.TrigoUIAxes.NextPlot = NetxPlotMode;
           app.EditField.Value = NetxPlotMode;
            
            app.TrigoErrorLabel1.Visible = 'Off';
            
            % Prüfen Funktionseigabe
            debugcode = 0; % 0 = OK
            comp = contains(value,"x");
            app.EditField2.Value = debugcode;
            if comp == 1
                debug = fplot(app.TrigoUIAxes, @(x) eval(y));
                try
                    debug.XData >= -inf;
                catch 
                    app.TrigoErrorLabel1.Visible = 'On';
                    debugcode = 1; % 1 = Fehler in Eingabe
                    app.EditField2.Value = debugcode;
                end 
            else
                debugcode = 2; % 2 = Enhält kein X
                app.EditField2.Value = debugcode;
                app.TrigoErrorLabel1.Visible = 'On';
            end

            if debugcode == 0
                app.EditField2.Value = debugcode;
                app.EditField3.Value = y;
                fplot(app.TrigoUIAxes, @(x) eval(y));
            end
            
            xline(app.TrigoUIAxes, 0);
            yline(app.TrigoUIAxes, 0);
            
        end
        
        function PolyPlotGraph(app,coef,NetxPlotMode)
            
            app.PolyErrorLabel.Visible = 'Off';
            
            app.PolyUIAxes.NextPlot = NetxPlotMode;
            fplot(app.PolyUIAxes, poly2sym(coef));
            xline(app.PolyUIAxes, 0);
            yline(app.PolyUIAxes, 0);
            
            %% Anpassung der Skala wenn manuell
            if app.PolySkalaAutoSwitch.Value  == "On"
                %% Slider für X Achse
                if app.PolySliderX.Value ~= 0
                    minX = app.PolySliderX.Value * (-1);
                    maxX = app.PolySliderX.Value;
                    app.PolyUIAxes.XLim = [minX, maxX];
                end
                
                %% Slider für Y Achse
                if app.PolySliderY.Value ~= 0
                    minY = app.PolySliderY.Value * (-1);
                    maxY = app.PolySliderY.Value;
                    app.PolyUIAxes.YLim = [minY, maxY];
                end
            end
        end
        
        function FreiPlotGraph(app,y,NetxPlotMode,Nr)
            
            app.FreiUIAxes.NextPlot = NetxPlotMode;
            
            % Prüfen Funktionseigabe
            debugcode = 0; % 0 = OK
            comp = isempty(y); %% Keine Fehlermeldung wenn nicht eigegeben wird
            if comp == 0
                debug = fplot(app.FreiUIAxes, @(x) eval(y));
                try
                    debug.XData >= -inf;
                catch 
                    if Nr == 1
                        app.FreiErrorLabel1.Visible = 'On';
                    end
                    if Nr == 2
                        app.FreiErrorLabel2.Visible = 'On';
                    end
                    if Nr == 3
                        app.FreiErrorLabel3.Visible = 'On';
                    end
                    if Nr == 4
                        app.FreiErrorLabel4.Visible = 'On';
                    end
                    debugcode = 1; % 1 = Fehler in eingabe
                end 
            else
                debugcode = 2; % 2 = Leere String
            end

            if debugcode == 0
                writeHistorie(app,y,Nr);
                fplot(app.FreiUIAxes, @(x) eval(y));
            end
            
            xline(app.FreiUIAxes, 0);
            yline(app.FreiUIAxes, 0);
        end
        
        function writeHistorie(app,value,nrFunktion)
            fxvalue = append('f(x) = ',value);
            rowdata = {fxvalue,nrFunktion};
            app.UITable.Data = [app.UITable.Data; rowdata];
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            xline(app.PolyUIAxes, 0);
            yline(app.PolyUIAxes, 0);
            app.PolySliderX.Visible = 'Off';
            app.PolySliderY.Visible = 'Off';
            app.PolyAddButton.Visible = 'Off';
            app.PolyClearButton.Visible = 'Off';
            app.PolyErrorLabel.Visible = 'Off';
            
            app.TrigoErrorLabel1.Visible = 'Off';
            app.TrigoAddButton.Visible = 'Off';
            app.TrigoClearButton.Visible = 'Off';
            xline(app.TrigoUIAxes, 0);
            yline(app.TrigoUIAxes, 0);
            
            app.FreiErrorLabel1.Visible = 'Off';
            app.FreiErrorLabel2.Visible = 'Off';
            app.FreiErrorLabel3.Visible = 'Off';
            app.FreiErrorLabel4.Visible = 'Off';

            xline(app.FreiUIAxes, 0);
            yline(app.FreiUIAxes, 0);
            
        end

        % Button pushed function: PolyShowGraphButton
        function PolyShowGraphButtonPushed(app, event)
            %% Berechnung Graph
            coef=[
                  app.Poly5EditField.Value, ...
                  app.Poly4EditField.Value, ...
                  app.Poly3EditField.Value, ...
                  app.Poly2EditField.Value, ...
                  app.Poly1EditField.Value, ...
                  app.Poly0EditField.Value, ...
                 ]; % coefficients 
            
            
            NetxPlotMode = 'replacechildren';
            
            if range(coef) == 0
                app.PolyErrorLabel.Visible = 'On';
            else
                PolyPlotGraph(app,coef,NetxPlotMode);
                app.PolyShowGraphButton.Text = 'Replace Graph';
                app.PolyAddButton.Visible = 'On';
                app.PolyClearButton.Visible = 'On';
            end


        end

        % Callback function: TrigoShowGraphButton, TrigoWertEditField
        function TrigoShowGraphButtonPushed(app, event)
            func = app.TrigoDropDown.Value;
            a = lower(num2str(app.TrigoAmplitudeSpinner.Value));
            %x = linspace(-pi,pi,100);
            value = app.TrigoWertEditField.Value;

            NetxPlotMode = 'replacechildren';
            
            TrigoPlotGraph(app,a,func,value,NetxPlotMode);
            
            app.TrigoShowGraphButton.Text = 'Replace Graph';
            app.TrigoAddButton.Visible = 'On';
            app.TrigoClearButton.Visible = 'On';
            
        end

        % Callback function: FreiShowGraphButton, FreiXSwitch, 
        % FreiYSwitch
        function FreiShowGraphButtonPushed(app, event)
            y1 = lower(num2str(app.FreiWertEditField_1.Value));
            y2 = lower(num2str(app.FreiWertEditField_2.Value));
            y3 = lower(num2str(app.FreiWertEditField_3.Value));
            y4 = lower(num2str(app.FreiWertEditField_4.Value));

            %% Werte korrigiert ausgeben
            app.FreiWertEditField_1.Value = y1;
            app.FreiWertEditField_2.Value = y2;
            app.FreiWertEditField_3.Value = y3;
            app.FreiWertEditField_4.Value = y4;
   
            if app.FreiXSwitch.Value  == "Off"
                app.FreiUIAxes.XScale = 'linear';
            else
                app.FreiUIAxes.XScale = 'log';
            end
            
            if app.FreiYSwitch.Value  == "Off"
                app.FreiUIAxes.YScale = 'linear';
            else
                app.FreiUIAxes.YScale = 'log';
            end
            % Warnung Clearen
            app.FreiErrorLabel1.Visible = 'Off';
            app.FreiErrorLabel2.Visible = 'Off';
            app.FreiErrorLabel3.Visible = 'Off';
            app.FreiErrorLabel4.Visible = 'Off';
            
            %Aufruf alle 4 Funktionen
            NetxPlotMode = 'replacechildren';
            FreiPlotGraph(app,y1,NetxPlotMode,1);
            NetxPlotMode = 'add';
            FreiPlotGraph(app,y2,NetxPlotMode,2);
            FreiPlotGraph(app,y3,NetxPlotMode,3);
            FreiPlotGraph(app,y4,NetxPlotMode,4);
            
        end

        % Value changed function: PolySkalaAutoSwitch
        function PolySkalaAutoSwitchValueChanged(app, event)
            value = app.PolySkalaAutoSwitch.Value;
            if value  == "On"
                app.PolySliderX.Visible = 'Off';
                app.PolySliderY.Visible = 'Off';
               %%Reset des Skala zu Auto
                app.PolyUIAxes.XLim = [0, 1];
                app.PolyUIAxes.XLimMode = 'auto';
                app.PolyUIAxes.YLim = [0, 1];
                app.PolyUIAxes.YLimMode = 'auto';
            else
                %% Slider für X Achse
                app.PolySliderX.Visible = 'On';
                if app.PolySliderX.Value ~= 0
                    minX = app.PolySliderX.Value * (-1);
                    maxX = app.PolySliderX.Value;
                    app.PolyUIAxes.XLim = [minX, maxX];
                end
                %% Slider für Y Achse
                app.PolySliderY.Visible = 'On';
                if app.PolySliderY.Value ~= 0
                    minY = app.PolySliderY.Value * (-1);
                    maxY = app.PolySliderY.Value;
                    app.PolyUIAxes.YLim = [minY, maxY];
                end
            end
        end

        % Value changing function: PolySliderX
        function PolySliderXValueChanging(app, event)
            changingValue = event.Value;
            min = changingValue * (-1);
            max = changingValue;
            if min < max
                app.PolyUIAxes.XLim = [min, max];
            end
        end

        % Value changing function: PolySliderY
        function PolySliderYValueChanging(app, event)
            changingValue = event.Value;
            min = changingValue * (-1);
            max = changingValue;
            if min < max
                app.PolyUIAxes.YLim = [min, max];
            end
        end

        % Button pushed function: TrigoAddButton
        function TrigoAddButtonPushed(app, event)
            func = app.TrigoDropDown.Value;
            a = lower(num2str(app.TrigoAmplitudeSpinner.Value));
            value = app.TrigoWertEditField.Value;

            NetxPlotMode = 'add';
            
            TrigoPlotGraph(app,a,func,value,NetxPlotMode);
        end

        % Button pushed function: TrigoClearButton
        function TrigoClearButtonPushed(app, event)
            app.TrigoUIAxes.NextPlot = 'replacechildren';
            fplot(app.TrigoUIAxes, 0);
            xline(app.TrigoUIAxes, 0);
            yline(app.TrigoUIAxes, 0);
            
            app.TrigoShowGraphButton.Text = 'Show Graph';
            app.TrigoAddButton.Visible = 'Off';
            app.TrigoClearButton.Visible = 'Off';
        end

        % Button pushed function: PolyAddButton
        function PolyAddButtonPushed(app, event)
            %% Berechnung Graph
            coef=[
                  app.Poly5EditField.Value, ...
                  app.Poly4EditField.Value, ...
                  app.Poly3EditField.Value, ...
                  app.Poly2EditField.Value, ...
                  app.Poly1EditField.Value, ...
                  app.Poly0EditField.Value, ...
                 ]; % coefficients 
            NetxPlotMode = 'add';
            if range(coef) == 0
                app.PolyErrorLabel.Visible = 'On';
            else
                PolyPlotGraph(app,coef,NetxPlotMode);
            end
        end

        % Button pushed function: PolyClearButton
        function PolyClearButtonPushed(app, event)
            app.PolyUIAxes.NextPlot = 'replacechildren';
            fplot(app.PolyUIAxes, 0);
            xline(app.PolyUIAxes, 0);
            yline(app.PolyUIAxes, 0);
            
            app.PolyShowGraphButton.Text = 'Show Graph';
            app.PolyAddButton.Visible = 'Off';
            app.PolyClearButton.Visible = 'Off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MathGraphsUIFigure = uifigure('Visible', 'off');
            app.MathGraphsUIFigure.Name = 'MathGraphs';
            app.TabGroup = uitabgroup(app.MathGraphsUIFigure);
            app.TabPoly = uitab(app.TabGroup);
            app.TabPoly.Title = 'Polynom Funktionen';
            app.GridLayout = uigridlayout(app.TabPoly);
            app.HTMLx4_2 = uihtml(app.GridLayout);
            app.HTMLx4_2.HTMLSource = '<p style="font-family: helvetica; margin: 0px; padding: 0px; text-align: center;"><small>x<sup>5</sup></small></p>';
            app.PolyClearButton = uibutton(app.GridLayout, 'push');
            app.PolyClearButton.ButtonPushedFcn = createCallbackFcn(app, @PolyClearButtonPushed, true);
            app.PolyClearButton.Text = 'Clear All';
            app.PolyAddButton = uibutton(app.GridLayout, 'push');
            app.PolyAddButton.ButtonPushedFcn = createCallbackFcn(app, @PolyAddButtonPushed, true);
            app.PolyAddButton.Text = 'Add Graph';
            app.HTMLx3 = uihtml(app.GridLayout);
            app.HTMLx3.HTMLSource = '<p style="font-family: helvetica; margin: 0px; padding: 0px; text-align: center;"><small>x<sup>3</sup></small></p>';
            app.HTMLoffset = uihtml(app.GridLayout);
            app.HTMLoffset.HTMLSource = '<p style="font-family: helvetica; margin: 0px; padding: 0px;text-align: bottom;"><small>offset</small></small></p';
            app.HTMLx2 = uihtml(app.GridLayout);
            app.HTMLx2.HTMLSource = '<p style="font-family: helvetica; margin: 0px; padding: 0px; text-align: center;"><small>x<sup>2</sup></small></p>';
            app.HTMLx4 = uihtml(app.GridLayout);
            app.HTMLx4.HTMLSource = '<p style="font-family: helvetica; margin: 0px; padding: 0px; text-align: center;"><small>x<sup>4</sup></small></p>';
            app.PolySliderX = uislider(app.GridLayout);
            app.PolySliderX.Limits = [0 10];
            app.PolySliderX.MajorTicks = [0 2 4 6 8 10];
            app.PolySliderX.MajorTickLabels = {'0', '2', '4', '6', '8', '10'};
            app.PolySliderX.ValueChangingFcn = createCallbackFcn(app, @PolySliderXValueChanging, true);
            app.PolySliderX.MinorTicks = [0.5 1 1.5 2.5 3 3.5 4.5 5 5.5 6.5 7 7.5 8.5 9 9.5];
            app.PolyShowGraphButton = uibutton(app.GridLayout, 'push');
            app.PolyShowGraphButton.ButtonPushedFcn = createCallbackFcn(app, @PolyShowGraphButtonPushed, true);
            app.PolyShowGraphButton.Text = 'Show Graph';
            app.Poly0EditField = uieditfield(app.GridLayout, 'numeric');
            app.Poly1EditField = uieditfield(app.GridLayout, 'numeric');
            app.Poly2EditField = uieditfield(app.GridLayout, 'numeric');
            app.Poly5EditField = uieditfield(app.GridLayout, 'numeric');
            app.Poly4EditField = uieditfield(app.GridLayout, 'numeric');
            app.Poly3EditField = uieditfield(app.GridLayout, 'numeric');
            app.PolySkalaAutoSwitch = uiswitch(app.GridLayout, 'slider');
            app.PolySkalaAutoSwitch.Items = {'On', 'Off'};
            app.PolySkalaAutoSwitch.ValueChangedFcn = createCallbackFcn(app, @PolySkalaAutoSwitchValueChanged, true);
            app.PolySkalaAutoSwitch.Value = 'On';
            app.AutoLabel = uilabel(app.GridLayout);
            app.AutoLabel.Text = 'Auto';
            app.PolySliderY = uislider(app.GridLayout);
            app.PolySliderY.Limits = [0 20];
            app.PolySliderY.MajorTicks = [0 2 4 6 8 10 12 14 16 18 20];
            app.PolySliderY.MajorTickLabels = {'0', '2', '4', '6', '8', '10', '12', '14', '16', '18', '20'};
            app.PolySliderY.ValueChangingFcn = createCallbackFcn(app, @PolySliderYValueChanging, true);
            app.PolySliderY.MinorTicks = [1 3 5 7 9 11 13 15 17 19];
            app.HTMLx1 = uihtml(app.GridLayout);
            app.HTMLx1.HTMLSource = '<p style="font-family: helvetica; margin: 0px; padding: 0px; text-align: center;"><small>x<sup>1</sup></small></p>';
            app.PolyErrorLabel = uilabel(app.GridLayout);
            app.PolyErrorLabel.Text = 'Bitte wählen Sie Koeffizienten aus ';
            app.PolyUIAxes = uiaxes(app.GridLayout);
            xlabel(app.PolyUIAxes, 'X-Achse')
            ylabel(app.PolyUIAxes, 'Y-Achse')
            zlabel(app.PolyUIAxes, 'Z-Achse')
            app.TabTrigo = uitab(app.TabGroup);
            app.TabTrigo.Title = 'Trigonometrische Funktionen';
            app.TrigoShowGraphButton = uibutton(app.TabTrigo, 'push');
            app.TrigoShowGraphButton.ButtonPushedFcn = createCallbackFcn(app, @TrigoShowGraphButtonPushed, true);
            app.TrigoShowGraphButton.Text = 'Show Graph';
            app.TrigoDropDown = uidropdown(app.TabTrigo);
            app.TrigoDropDown.Items = {'Sinus', 'Cosinus', 'Tangens'};
            app.TrigoDropDown.Value = 'Sinus';
            app.TrigoWertEditField = uieditfield(app.TabTrigo, 'text');
            app.TrigoWertEditField.ValueChangedFcn = createCallbackFcn(app, @TrigoShowGraphButtonPushed, true);
            app.TrigoWertEditField.Value = 'x';
            app.AmplitudeSpinnerLabel = uilabel(app.TabTrigo);
            app.AmplitudeSpinnerLabel.Text = 'Amplitude';
            app.TrigoAmplitudeSpinner = uispinner(app.TabTrigo);
            app.TrigoAmplitudeSpinner.Value = 1;
            app.TrigoAddButton = uibutton(app.TabTrigo, 'push');
            app.TrigoAddButton.ButtonPushedFcn = createCallbackFcn(app, @TrigoAddButtonPushed, true);
            app.TrigoAddButton.Text = 'Add Graph';
            app.TrigoClearButton = uibutton(app.TabTrigo, 'push');
            app.TrigoClearButton.ButtonPushedFcn = createCallbackFcn(app, @TrigoClearButtonPushed, true);
            app.TrigoClearButton.Text = 'Clear All';
            app.SkalierungYEditFieldLabel = uilabel(app.TabTrigo);
            app.SkalierungYEditFieldLabel.Enable = 'off';
            app.SkalierungYEditFieldLabel.Visible = 'off';
            app.SkalierungYEditFieldLabel.Text = 'SkalierungY';
            app.SkalierungYEditField = uieditfield(app.TabTrigo, 'numeric');
            app.SkalierungYEditField.Limits = [0 Inf];
            app.SkalierungYEditField.Editable = 'off';
            app.SkalierungYEditField.Enable = 'off';
            app.SkalierungYEditField.Visible = 'off';
            app.SkalierungYEditField.Value = 2;
            app.SkalierungXEditFieldLabel = uilabel(app.TabTrigo);
            app.SkalierungXEditFieldLabel.Enable = 'off';
            app.SkalierungXEditFieldLabel.Visible = 'off';
            app.SkalierungXEditFieldLabel.Text = 'SkalierungX';
            app.SkalierungXEditField = uieditfield(app.TabTrigo, 'numeric');
            app.SkalierungXEditField.Editable = 'off';
            app.SkalierungXEditField.Enable = 'off';
            app.SkalierungXEditField.Visible = 'off';
            app.SkalierungXEditField.Value = 6.28318530717959;
            app.TrigoErrorLabel1 = uilabel(app.TabTrigo);
            app.TrigoErrorLabel1.Text = 'Ungültige Eingabe';
            app.EditField = uieditfield(app.TabTrigo, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Enable = 'off';
            app.EditField.Visible = 'off';
            app.EditField3 = uieditfield(app.TabTrigo, 'text');
            app.EditField3.Editable = 'off';
            app.EditField3.Enable = 'off';
            app.EditField3.Visible = 'off';
            app.EditField2 = uieditfield(app.TabTrigo, 'numeric');
            app.EditField2.Editable = 'off';
            app.EditField2.Enable = 'off';
            app.EditField2.Visible = 'off';
            app.TrigoUIAxes = uiaxes(app.TabTrigo);
            xlabel(app.TrigoUIAxes, 'X-Achse')
            ylabel(app.TrigoUIAxes, 'Y-Achse')
            zlabel(app.TrigoUIAxes, 'Z-Achse')
            app.TrigoUIAxes.ZLim = [0 1];
            app.TabFrei = uitab(app.TabGroup);
            app.TabFrei.Title = 'Weitere Funktionen';
            app.GridLayout2 = uigridlayout(app.TabFrei);
            app.FreiErrorLabel4 = uilabel(app.GridLayout2);
            app.FreiErrorLabel4.Text = 'Ungültige Funktion';
            app.FreiErrorLabel3 = uilabel(app.GridLayout2);
            app.FreiErrorLabel3.Text = 'Ungültige Funktion';
            app.FreiFunktion4Label = uilabel(app.GridLayout2);
            app.FreiFunktion4Label.Text = 'Funktion 4:';
            app.FreiFunktion3Label = uilabel(app.GridLayout2);
            app.FreiFunktion3Label.Text = 'Funktion 3:';
            app.FreiWertEditField_3 = uieditfield(app.GridLayout2, 'text');
            app.FreiWertEditField_4 = uieditfield(app.GridLayout2, 'text');
            app.FreiErrorLabel2 = uilabel(app.GridLayout2);
            app.FreiErrorLabel2.Text = 'Ungültige Funktion';
            app.FreiErrorLabel1 = uilabel(app.GridLayout2);
            app.FreiErrorLabel1.Text = 'Ungültige Funktion';
            app.FreiSkalaLogarythmierenLabel = uilabel(app.GridLayout2);
            app.FreiSkalaLogarythmierenLabel.Text = 'Skala Logarithmieren';
            app.FreiFunktion2Label = uilabel(app.GridLayout2);
            app.FreiFunktion2Label.Text = 'Funktion 2:';
            app.FreiFunktion1Label = uilabel(app.GridLayout2);
            app.FreiFunktion1Label.Text = 'Funktion 1:';
            app.FreiWertEditField_1 = uieditfield(app.GridLayout2, 'text');
            app.FreiWertEditField_2 = uieditfield(app.GridLayout2, 'text');
            app.FreiXSwitch = uiswitch(app.GridLayout2, 'slider');
            app.FreiXSwitch.ValueChangedFcn = createCallbackFcn(app, @FreiShowGraphButtonPushed, true);
            app.XSwitchLabel = uilabel(app.GridLayout2);
            app.XSwitchLabel.Text = 'X';
            app.FreiYSwitch = uiswitch(app.GridLayout2, 'slider');
            app.FreiYSwitch.ValueChangedFcn = createCallbackFcn(app, @FreiShowGraphButtonPushed, true);
            app.YSwitchLabel = uilabel(app.GridLayout2);
            app.YSwitchLabel.Text = 'Y';
            app.FreiShowGraphButton = uibutton(app.GridLayout2, 'push');
            app.FreiShowGraphButton.ButtonPushedFcn = createCallbackFcn(app, @FreiShowGraphButtonPushed, true);
            app.FreiShowGraphButton.Text = 'Show Graphs';
            app.FreiUIAxes = uiaxes(app.GridLayout2);
            xlabel(app.FreiUIAxes, 'X-Achse')
            ylabel(app.FreiUIAxes, 'Y-Achse')
            zlabel(app.FreiUIAxes, 'Z-Achse')
            app.HistorieTab = uitab(app.TabGroup);
            app.HistorieTab.Title = 'Historie';
            app.UITable = uitable(app.HistorieTab);
            app.UITable.ColumnName = {'Funktion'; 'Eingabefeld'};
            app.UITable.RowName = {};
            app.HistoriederausgefhrtenFreitextFunktionenLabel = uilabel(app.HistorieTab);
            app.HistoriederausgefhrtenFreitextFunktionenLabel.Text = 'Historie der ausgeführten Freitext-Funktionen';
            % Show the figure after all components are created
            app.MathGraphsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MathGraphs

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MathGraphsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MathGraphsUIFigure)
        end
    end
end